classdef STS_Test_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        STSUIFigure                 matlab.ui.Figure
        LoadAccelerationDataButton  matlab.ui.control.Button
        ExportSRSDataButton         matlab.ui.control.Button
        ExportSRSFigureButton       matlab.ui.control.Button
        ShockTestStandDataAnalysisProgramLabel  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        AccelerationGraph           matlab.ui.control.UIAxes
        Panel_2                     matlab.ui.container.Panel
        SRSGraph                    matlab.ui.control.UIAxes
        MeetsNASASpecLampLabel      matlab.ui.control.Label
        MeetsNASASpecLamp           matlab.ui.control.Lamp
        DataLoadedLampLabel         matlab.ui.control.Label
        DataLoadedLamp              matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadAccelerationDataButton
        function LoadAccelerationDataButtonPushed(app, event)
            
            % Prompt User for File
            [file,path] = uigetfile('*.csv');
            filename = fullfile(path, file);
            
            % Load Data if File Exists
            if(exist(filename,'file') == 2)
                app.DataLoadedLamp.Color = 'green';
                data = readtable(filename);
            else
                app.DataLoadedLamp.Color = 'red';
                return;
            end
            
            acc_mat = table2array(data);
            
            
            % --- Generate Fake Data for Test ---
            t_sim = 5E-3; %seconds
            t_step = 0.00000005; %seconds
            
            % Constants
            g = 9.80665;
            
            % SRS Test Frequencies
            f_n = [30, 100, 700, 1000, 1500, 5000, 10000]; %Hz
            Q = 10;
            
            % SRS Goals
            acc_g_n = [5, 100, 1500, 2400, 4000, 4000, 2000]; %G's
            
            % Half-Sine Shock Pulse
            t_pulse_duration = 0.37E-3; %seconds
            a_peak_pulse = 3250; %G's
            
            [u_pulse,t_pulse] = gensig('sin', 2*t_pulse_duration, 2*t_pulse_duration, t_step); %Half-Sine
            u_pulse = u_pulse*a_peak_pulse*g; %Scale to m/s^2
            t_zeros = ((length(t_pulse)*t_step):t_step:t_sim)';
            u_zeros = zeros(length(t_zeros),1);
            t = vertcat(t_pulse,t_zeros);
            u = vertcat(u_pulse,u_zeros);
            
            %--- Prep & Test Data ---
            
            plot(app.AccelerationGraph,t,u);
            
            % --- SRS Generation & Plot ---
            k = 1;
            zeta = 1/(2*Q);
            w_n = f_n*2*pi; %rad/s
            
            % Simulate System Responses
            s = tf('s');
            sys_resp = zeros(length(w_n),length(t));
            for i = 1:length(w_n)
                sys = (k*w_n(i)^2)/(s^2+2*zeta*w_n(i)*s+w_n(i)^2);
                sys_resp(i,:) = lsim(sys,u,t);
            end
            
            % Extract Max Magnitude of Each Frequency
            acc_max_w_n = zeros(1,length(w_n));
            for i = 1:length(w_n)
                acc_max_w_n(i) = max(sys_resp(i,:));
            end
            
            acc_max_w_n = acc_max_w_n/g;
            
            %Plot SRS
            hold(app.SRSGraph, 'on');
            plot(app.SRSGraph,f_n,acc_max_w_n);
            plot(app.SRSGraph,f_n,acc_g_n);
            legend(app.SRSGraph,"Test Results","NASA GEVS",'Location',"best");
            hold(app.SRSGraph, 'off');
            
            % --- Check Results against NASA GEVS Spec ---
            meetsRequirements = 1;
            for i = 1:length(f_n)
                if(acc_max_w_n(i) < acc_g_n(i))
                    meetsRequirements = 0;
                end
            end
            if(meetsRequirements)
                app.MeetsNASASpecLamp.Color = 'green';
            else
                app.MeetsNASASpecLamp.Color = 'red';
            end
            
        end

        % Button pushed function: ExportSRSFigureButton
        function ExportSRSFigureButtonPushed(app, event)
            [file, path, filterindex] = uiputfile({'*.png', 'PNG (*.png)'; '*.*', 'All Files (*.*)'});
            filename = fullfile(path, file);
            
%             FigureSave = figure;
%             copyobj(app.SRSGraph,FigureSave)            
%             saveas(FigureSave,filename,'png');
        end

        % Button pushed function: ExportSRSDataButton
        function ExportSRSDataButtonPushed(app, event)
            [filename, pathname, filterindex] = uiputfile({'*.csv', 'CSV (*.csv)'; '*.*', 'All Files (*.*)'});
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.STSUIFigure = uifigure('Visible', 'off');
            app.STSUIFigure.Name = 'STS';
            app.LoadAccelerationDataButton = uibutton(app.STSUIFigure, 'push');
            app.LoadAccelerationDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAccelerationDataButtonPushed, true);
            app.LoadAccelerationDataButton.Text = 'Load Acceleration Data';
            app.ExportSRSDataButton = uibutton(app.STSUIFigure, 'push');
            app.ExportSRSDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSRSDataButtonPushed, true);
            app.ExportSRSDataButton.Text = 'Export SRS Data';
            app.ExportSRSFigureButton = uibutton(app.STSUIFigure, 'push');
            app.ExportSRSFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSRSFigureButtonPushed, true);
            app.ExportSRSFigureButton.Text = 'Export SRS Figure';
            app.ShockTestStandDataAnalysisProgramLabel = uilabel(app.STSUIFigure);
            app.ShockTestStandDataAnalysisProgramLabel.Text = 'Shock Test Stand - Data Analysis Program';
            app.Panel = uipanel(app.STSUIFigure);
            app.AccelerationGraph = uiaxes(app.Panel);
            title(app.AccelerationGraph, 'Accleration Data')
            xlabel(app.AccelerationGraph, 'Time (s)')
            ylabel(app.AccelerationGraph, 'Acceleration (G''s)')
            app.Panel_2 = uipanel(app.STSUIFigure);
            app.SRSGraph = uiaxes(app.Panel_2);
            title(app.SRSGraph, 'Shock Response Spectrum')
            xlabel(app.SRSGraph, 'Frequency (Hz)')
            ylabel(app.SRSGraph, 'Acceleration (G''s)')
            app.SRSGraph.XScale = 'log';
            app.SRSGraph.YScale = 'log';
            app.MeetsNASASpecLampLabel = uilabel(app.STSUIFigure);
            app.MeetsNASASpecLampLabel.Text = 'Meets NASA Spec';
            app.MeetsNASASpecLamp = uilamp(app.STSUIFigure);
            app.DataLoadedLampLabel = uilabel(app.STSUIFigure);
            app.DataLoadedLampLabel.Text = 'Data Loaded';
            app.DataLoadedLamp = uilamp(app.STSUIFigure);
            % Show the figure after all components are created
            app.STSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STS_Test_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.STSUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.STSUIFigure)
        end
    end
end